classdef ESRApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        LeftPanel               matlab.ui.container.Panel
        RightPanel              matlab.ui.container.Panel
        PLOTButton              matlab.ui.control.Button
        SaveFigureButton        matlab.ui.control.Button
        FolderEditFieldLabel_2  matlab.ui.control.Label
        BrowsefolderButton      matlab.ui.control.Button
        MEDFILTEditField        matlab.ui.control.EditField
        MEDFILTEditFieldLabel   matlab.ui.control.Label
        BINNINGEditField        matlab.ui.control.EditField
        BINNINGEditFieldLabel   matlab.ui.control.Label
        FolderEditField         matlab.ui.control.EditField
        FolderEditFieldLabel    matlab.ui.control.Label
        ANGLEdegEditField       matlab.ui.control.EditField
        ANGLEdegEditFieldLabel  matlab.ui.control.Label
        UIAxes                  matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowsefolderButton
        function BrowsefolderButtonPushed(app, event)
            % Open a dialog box to select the folder
            folder = uigetdir();
            % Set the text in the textBox2 to the selected folder
            app.FolderEditField.Value=folder;
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)

        end

        % Button pushed function: PLOTButton
        function PLOTButtonPushed(app, event)
            % Get the folder, binning, and median filter parameters from the text boxes
            nb_slice=100;
            th=0.35
            folder = app.FolderEditField.Value;
            binning = str2double(app.BINNINGEditField.Value);
            medianFilter = str2double(app.ANGLEdegEditField.Value);
            angle = str2double(app.ANGLEdegEditField.Value);
            % Call the function that generates the plot
            Sz=medianFilter;
            folder = cat(2,folder,'\');
            trialpath=folder;
            S = dir(folder);
            Nsubfolder=sum([S(~ismember({S.name},{'.','..'})).isdir]);
            datadir=cat(2,trialpath,'\1\DATA\');
            DataFolderinfo = dir(datadir);
            Samplefile=DataFolderinfo(3).name;
            Samplefileloc=cat(2,datadir,Samplefile);
            im1=imread(Samplefileloc);
            sli=slice_rect(im1,nb_slice,angle);
            Nb_f=length(DataFolderinfo)-2;
            PLcell_ESR=get_esr_dem_ang(folder, Nsubfolder, Nb_f, sli,th);
            Ccell_ESR=get_esr_maps(folder,Sz,PLcell_ESR);
            xf = Ccell_ESR{1}./1e9;
            M= Ccell_ESR{2};
            SizeSample=size(im1);
            W=SizeSample(1);
            H=SizeSample(2);
            largesize = max([W H]);
            b=10;
            Mmean=mean(M(10:end-10,b:end-b));
            % plot(obj.h.axes1,f, R,'b',f, I,'r',f, M,'k')
            % Update the plot
            plot(app.UIAxes,xf(b:end-b),Mmean)
            xlabel(app.UIAxes,'Frequency (GHz)')
            ylabel(app.UIAxes,'PL signal (au)')
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {27, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'Frequency (GHz)')
            ylabel(app.UIAxes, 'PL Signal (au)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.ANGLEdegEditFieldLabel = uilabel(app.RightPanel);
            app.ANGLEdegEditFieldLabel.Text = 'ANGLE (deg)';
            app.ANGLEdegEditField = uieditfield(app.RightPanel, 'text');
            app.FolderEditFieldLabel = uilabel(app.RightPanel);
            app.FolderEditFieldLabel.Text = 'Folder';
            app.FolderEditField = uieditfield(app.RightPanel, 'text');
            app.BINNINGEditFieldLabel = uilabel(app.RightPanel);
            app.BINNINGEditFieldLabel.Text = 'BINNING';
            app.BINNINGEditField = uieditfield(app.RightPanel, 'text');
            app.MEDFILTEditFieldLabel = uilabel(app.RightPanel);
            app.MEDFILTEditFieldLabel.Text = 'MEDFILT';
            app.MEDFILTEditField = uieditfield(app.RightPanel, 'text');
            app.BrowsefolderButton = uibutton(app.RightPanel, 'push');
            app.BrowsefolderButton.ButtonPushedFcn = createCallbackFcn(app, @BrowsefolderButtonPushed, true);
            app.BrowsefolderButton.Text = 'Browse folder';
            app.FolderEditFieldLabel_2 = uilabel(app.RightPanel);
            app.FolderEditFieldLabel_2.Text = 'Created by Ari and Charles';
            app.SaveFigureButton = uibutton(app.RightPanel, 'push');
            app.SaveFigureButton.Text = 'Save Figure';
            app.PLOTButton = uibutton(app.RightPanel, 'push');
            app.PLOTButton.ButtonPushedFcn = createCallbackFcn(app, @PLOTButtonPushed, true);
            app.PLOTButton.Text = 'PLOT!';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ESRApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end